classdef RipAnalysis < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    TabGroup                      matlab.ui.container.TabGroup
    HomeTab                       matlab.ui.container.Tab
    StatusLabel_2                 matlab.ui.control.Label
    StatusLabel                   matlab.ui.control.Label
    Label                         matlab.ui.control.Label
    ChangeStatusButton            matlab.ui.control.Button
    FilelistindexLabel            matlab.ui.control.Label
    UseFilelistmCheckBox          matlab.ui.control.CheckBox
    FilelistindexSpinner          matlab.ui.control.Spinner
    ExportfigureButton            matlab.ui.control.Button
    RemoveripszipsButton          matlab.ui.control.Button
    OutputfolderTextArea          matlab.ui.control.TextArea
    OutputfolderTextArea_2Label   matlab.ui.control.Label
    DatafileTextArea              matlab.ui.control.TextArea
    DatafileTextArea_2Label       matlab.ui.control.Label
    DatafolderTextArea            matlab.ui.control.TextArea
    DatafolderTextArea_2Label     matlab.ui.control.Label
    yaxisButtonGroup              matlab.ui.container.ButtonGroup
    TButton                       matlab.ui.control.RadioButton
    xButton                       matlab.ui.control.RadioButton
    fButton                       matlab.ui.control.RadioButton
    RemoveintervalLabel           matlab.ui.control.Label
    AtendButton                   matlab.ui.control.Button
    AtbeginningButton             matlab.ui.control.Button
    xaxisButtonGroup              matlab.ui.container.ButtonGroup
    RecordButton                  matlab.ui.control.RadioButton
    TimesButton                   matlab.ui.control.RadioButton
    AddripzipButton               matlab.ui.control.Button
    SelectfolderButton_2          matlab.ui.control.Button
    SaveButton                    matlab.ui.control.Button
    RestoreviewButton             matlab.ui.control.Button
    SelectfileButton              matlab.ui.control.Button
    SelectfolderButton            matlab.ui.control.Button
    UIAxes                        matlab.ui.control.UIAxes
    SinglefvsxtracesTab           matlab.ui.container.Tab
    HidefittedlinesCheckBox       matlab.ui.control.CheckBox
    ShowtracesButtonGroup         matlab.ui.container.ButtonGroup
    RelaxButton                   matlab.ui.control.RadioButton
    PullButton                    matlab.ui.control.RadioButton
    BothButton                    matlab.ui.control.RadioButton
    allcyclesLabel                matlab.ui.control.Label
    ExportfigureButton_2          matlab.ui.control.Button
    dx2Label                      matlab.ui.control.Label
    xLabel_2                      matlab.ui.control.Label
    f2Label                       matlab.ui.control.Label
    ForceLabel_2                  matlab.ui.control.Label
    t23Label                      matlab.ui.control.Label
    EndLabel_2                    matlab.ui.control.Label
    t22Label                      matlab.ui.control.Label
    Event2Label                   matlab.ui.control.Label
    t21Label                      matlab.ui.control.Label
    StartLabel_2                  matlab.ui.control.Label
    Trace2Label                   matlab.ui.control.Label
    dx1Label                      matlab.ui.control.Label
    xLabel                        matlab.ui.control.Label
    f1Label                       matlab.ui.control.Label
    ForceLabel                    matlab.ui.control.Label
    t13Label                      matlab.ui.control.Label
    EndLabel                      matlab.ui.control.Label
    t12Label                      matlab.ui.control.Label
    Event1Label                   matlab.ui.control.Label
    t11Label                      matlab.ui.control.Label
    StartLabel                    matlab.ui.control.Label
    Trace1Label                   matlab.ui.control.Label
    CycletypeButtonGroup          matlab.ui.container.ButtonGroup
    RelaxpullButton               matlab.ui.control.RadioButton
    PullrelaxButton               matlab.ui.control.RadioButton
    CyclenoSpinner                matlab.ui.control.Spinner
    CyclenoSpinnerLabel           matlab.ui.control.Label
    UIAxes4                       matlab.ui.control.UIAxes
    UIAxes3                       matlab.ui.control.UIAxes
    MultiplefvsxtracesTab         matlab.ui.container.Tab
    ExportgraphButton             matlab.ui.control.Button
    StarttimeEditField            matlab.ui.control.NumericEditField
    StarttimeEditFieldLabel       matlab.ui.control.Label
    NumberofcyclesEditField       matlab.ui.control.NumericEditField
    NumberofcyclesEditFieldLabel  matlab.ui.control.Label
    CyclespacingnmEditField       matlab.ui.control.NumericEditField
    CyclespacingnmEditFieldLabel  matlab.ui.control.Label
    UIAxes5                       matlab.ui.control.UIAxes
    UIAxes6                       matlab.ui.control.UIAxes
  end


  % Version 0.5: Using Tab Groups to include controls for 
  % showing force vs. trap position x
  properties (Access = private)
    Datafolder % Current folder path for experiment data files
    Datafile % Experiment data file name
    traces % arrays of pull and relax traces
    ncycles % Current number of cycles
    cycleno % Index of current cycle in traces.pull and traces.relax
    h % struct of graphics handles to the current cycle plot
    settings % Copy of settings struct in RipAnalysis_settings
    filedata % Holds all data returned from analyse_experiment
    h2 %struct of graphics handles to the Single force vs x trace plot
    par % Parameter struct
    % if a rip is identified in cycle i
    Outputfolder % Folder for results Matlab tables
    Multiple_f_x % State for app.UIAxes5
    h3 % struct of graphics handles to the Multiple f vs x traces tab
    filelist % List of experiments file names (icluding subfolders under Datafolder)
    fileno % Current index in filelist
    Status % Correction status of experiment: "No file", "Corrected", "Automatic" 
    cyclerange % indices of start, max/min(x), end end of current cycle
  end

  methods (Access = public)

  end

  methods (Access = private)

    function reset_axis_limits(app,~)
      n = app.CyclenoSpinner.Value;
      Xlimits = [min(app.traces.pull(n).x),max(app.traces.pull(n).x)];
      Ylimits = [min(app.traces.pull(n).f),max(app.traces.pull(n).f)];
      set(app.UIAxes,'Xlim',Xlimits);
      set(app.UIAxes,'Ylim',Ylimits);
    end

    function read_experiment(app)
      % file_full = strrep(fullfile(app.Datafolder,app.Datafile),'\','/');
      [Trip,Tzip,data,peakpos,valleypos] = analyse_experiment(app.Datafile);
      app.filedata.filename = app.Datafile;
      app.filedata.t = data(:,1);
      app.filedata.f = data(:,2);
      app.filedata.x = data(:,3);
      app.filedata.T = data(:,4);
      app.filedata.recordno = (1:size(data,1))';
      app.filedata.peakpos = peakpos;
      app.filedata.valleypos = valleypos;
      app.filedata.limits = [1,size(data,1)];
      [pull,relax] = table2trace(app,Trip,Tzip);
      app.traces.pull = pull;
      app.traces.relax = relax; 
      plot_file(app,app.UIAxes)
    end

    function plot_file(app,axes,limx)
      % Plots force or extent vs time in the upper axes

      % initialise variables
      riptime = [];
      ripforce = [];
      riprecord = [];
      ziptime = [];
      zipforce = [];
      ziprecord = [];
      if ~isempty(app.traces.pull)
        riptime = vertcat(app.traces.pull.time);
        ripforce = vertcat(app.traces.pull.force);
        riprecord = find(ismember(app.filedata.t,riptime));
      end
      if ~isempty(app.traces.relax)
        ziptime = vertcat(app.traces.relax.time);
        zipforce = vertcat(app.traces.relax.force);
        ziprecord = find(ismember(app.filedata.t,ziptime));
      end
      xrange = app.filedata.limits(1):app.filedata.limits(2);
      if app.fButton.Value
        yvar = app.filedata.f(xrange);
        ylbl = 'pN';
      elseif app.xButton.Value
        yvar = app.filedata.x(xrange);
        ylbl = 'nm';
      else
        yvar = app.filedata.T(xrange);
        ylbl = '°C';
      end
      if app.TimesButton.Value
        xvar = app.filedata.t(xrange);
        xrip = riptime;
        xzip = ziptime;
        xlbl = 'Timestamp (s)';
      else
        xvar = app.filedata.recordno(xrange);
        xrip = riprecord;
        xzip = ziprecord;
        xlbl = 'Record no';
      end
      y1 = min(yvar);
      y2 = max(yvar);
      yrange = y2-y1;
      y1 = y1 - 0.05*max(yrange,1);
      y2 = y2 + 0.05*max(yrange,1);
      hold(axes,"off")
      xlim(axes,[xvar(1),xvar(end)]);
      ylim(axes,[y1,y2]);
      plot(axes,xvar,yvar);
      if app.fButton.Value
        hold(axes,"on")
        plot(axes,xrip,ripforce,'*r');
        plot(axes,xzip,zipforce,'ok','MarkerFaceColor',[1 1 1]);
      end
      xlim(axes,[xvar(1),xvar(end)]);
      title(axes,app.Datafile,'interpreter','none');
      xlabel(axes,xlbl);
      ylabel(axes,ylbl);
      box(axes,'on');
      zoom(axes,'on');
      % app.UIAxes2.Toolbar.Visible = "off";
      app.xaxisButtonGroup.Visible = "on";
      if nargin > 2
        xlim(axes,limx)
      end
    end

    function file = matfilename(app)
      % Create filename for the uutput table mat file
      slashes = strfind(app.Datafolder,'/');
      part1 = extractAfter(app.Datafolder,slashes(end));
      part2 = strrep(app.Datafile,'.txt','.mat');
      file = strcat(part1,"_",part2);
    end


    function [localrips,localzips,visiblerips,visiblezips] = selectrip(app,mode)
      % creates a listdlg showing all rips and zips in view
      % Returns the rip and zip numbers in the visible part of the plot
      % and the tables converting local numbering to global
      %
      % mode = 'Single' or 'Multiple' (default)
      if nargin < 2
        mode = 'Multiple';  % allow selection of multiple rips
      end
      xrng = xlim(app.UIAxes);
      localrips = [];
      localzips = [];
      visiblerips = [];
      visiblezips = [];
      nrip = 0;
      nzip = 0;
      if ~isempty(app.traces.pull)
        riptimes = vertcat(app.traces.pull.time);
        if app.TimesButton.Value
          visiblerips = find(riptimes>=xrng(1) & riptimes <= xrng(2));
        else
          riprecords = find(ismember(app.filedata.t,riptimes));
          visiblerips = find(riprecords>=xrng(1) & riprecords <= xrng(2));
        end
        nrip = numel(visiblerips);
        ripforces = vertcat(app.traces.pull.force);
      end
      if ~isempty(app.traces.relax)
        if app.TimesButton.Value
          ziptimes = vertcat(app.traces.relax.time);
          visiblezips = find(ziptimes>=xrng(1) & ziptimes <= xrng(2));
        else
          ziprecords = find(ismember(app.filedata.t,ziptimes));
          visiblezips = find(ziprecords>=xrng(1) & ziprecords <= xrng(2));
        end
        nzip = numel(visiblezips);
        zipforces = vertcat(app.traces.relax.force);
      end

      if nrip + nzip < 1
        msgbox('No rips or zips visible. Please pan and zoome to another range');
        return
      elseif nrip + nzip > 20
        msgbox('More than 20 rips or zips visible. Please zoom to a smaller range');
        return
      end
      % Number the visible rips and zips, starting at 1 and collect names
      % in riplist
      riplist = strings(nrip+nzip,1);
      for i = 1:nrip
        k = visiblerips(i);
        text(app.UIAxes,riptimes(k),ripforces(k)+1,num2str(i));
        riplist(i) = strcat("rip",num2str(i));
      end
      for i = 1:nzip
        k = visiblezips(i);
        text(app.UIAxes,ziptimes(k),zipforces(k)+1,num2str(i));
        riplist(i+nrip) = strcat("zip",num2str(i));
      end
      [indx,~] = listdlg('promptstring','Select rip or zip to modify', ...
        'ListString',riplist,'SelectionMode',mode);
      localrips = indx(indx<=nrip);
      localzips = indx(indx>nrip)-nrip;
    end

    function setlimit(app,type)
      % ***********************************************
      % Cast magic spell to make ginput crosshairs apppear in App window:
      fhv = app.UIFigure.HandleVisibility;        % Current state
      app.UIFigure.HandleVisibility = 'callback'; % Temporarily make UIFigure handle visible outside app
      set(0, 'CurrentFigure', app.UIFigure);     
      [x,~] = ginput(1);
      hh = xline(app.UIAxes,x);
      answer = questdlg("OK?");
      while strcmp(answer,'No')
        delete(hh);
        [x,~] = ginput(1);
        hh = xline(app.UIAxes,x);
        answer = questdlg("OK?");
      end
      app.UIFigure.HandleVisibility = fhv;
      % Magic spell lifted
      % ***********************************************
      delete(hh);
      if ~strcmp(answer,'Yes')
        return
      end
      % xline(app.UIAxes,x);
      if app.RecordButton.Value
        r = x;
      else
        % r = find(app.filedata.t==x);
        [~,r] = min(abs(app.filedata.t-x));
      end

      switch type
        case 'Start'
          app.filedata.limits(1) = r;
        case 'End'
          app.filedata.limits(2) = r;
      end
      ok = app.filedata.peakpos>app.filedata.limits(1) & ...
        app.filedata.peakpos<app.filedata.limits(2);
      app.filedata.peakpos = app.filedata.peakpos(ok);
      ok = app.filedata.valleypos>app.filedata.limits(1) & ...
        app.filedata.valleypos<app.filedata.limits(2);
      app.filedata.valleypos = app.filedata.valleypos(ok);      

      plot_file(app,app.UIAxes)
    end

    function [answer,new,sgn] = tryrip(app)
      % Called by AddripzipButtonPushed to try out adding a rip
      zoom(app.UIAxes,'on');
      % msg = 'To zoom or pan: move message box outside axes. Click OK when ready.';
      % waitfor(msgbox(msg));
      new = [];
      [rip,~] = ginput(1);
      t = app.filedata.t;
      f = app.filedata.f;
      x = app.filedata.x;
      if app.TimesButton.Value
        ix = find(t>rip,1);
      else
        ix = rip;
      end
      % ix is the index in t,x anf f correspondig to the selected point
      lastpeak = find(app.filedata.peakpos < ix,1,'last');
      lastpeakpos = app.filedata.peakpos(lastpeak);
      lastvalley = find(app.filedata.valleypos < ix,1,'last');
      lastvalleypos = app.filedata.valleypos(lastvalley);
      nolastpeak = isempty(lastpeak);     % No peaks before ix
      nolastvalley = isempty(lastvalley); % No valleys before ix
      if nolastpeak & nolastvalley
        msgbox("Unable to add rip/zip before first peak or valley");
        answer = "No";
        sgn = 1;
        return
      elseif nolastpeak
        lastpeak = 0;
        lastpeakpos = 1;
      elseif nolastvalley
        lastvalley = 0;
        lastvalleypos = 1;
      end

      if lastpeakpos > lastvalleypos  % pulling trace
        tracerng = lastpeakpos:app.filedata.valleypos(lastvalley+1);
      else
        tracerng = lastvalleypos:app.filedata.peakpos(lastpeak+1);
      end
      sgn = sign(lastvalleypos-lastpeakpos);  % 1: pull. -1: relax
      rng = tracerng(valid_trace_part(f(tracerng),sgn,app.par));
      % rng is the index range for the valid part of the trace around ix
      if ~ismember(ix,rng)  % ix is not inside a valid trace part
        answer = "No";
        return
      end
      if app.PullrelaxButton.Value
        app.cycleno = lastvalleypos;
      else
        app.cycleno = lastpeakpos; 
      end
      % fitting range before rip/zip
      nf = numel(rng);
      fitb_start = max(ix - round(app.par.maxfitfraction*nf),rng(1));
      fitrange_b = fitb_start:ix;
      % fitting range after rip/zip
      fita_end = min(ix+2*app.par.ripsteps+round(app.par.maxfitfraction*nf),rng(end));
      fitrange_a = (ix + +2*app.par.ripsteps):fita_end;
      % Fit straight lines before and after rip/zip:
      pft_b = polyfit(t(fitrange_b),f(fitrange_b),1);
      pft_a = polyfit(t(fitrange_a),f(fitrange_a),1);
      if app.TimesButton.Value
        htry(1) = plot(app.UIAxes,t(fitrange_b),polyval(pft_b,t(fitrange_b)),'k');
        htry(2) = plot(app.UIAxes,t(fitrange_a),polyval(pft_a,t(fitrange_a)),'k');
        htry(3) = plot(app.UIAxes,t(ix),polyval(pft_b,t(ix)),'*r');
      else
        pf_b = polyfit(fitrange_b,f(fitrange_b),1);
        pf_a = polyfit(fitrange_a,f(fitrange_a),1);
        htry(1) = plot(app.UIAxes,fitrange_b,polyval(pf_b,fitrange_b),'k');
        htry(2) = plot(app.UIAxes,fitrange_a,polyval(pf_a,fitrange_a),'k');
        htry(3) = plot(app.UIAxes,ix,polyval(pf_b,ix),'*r');
      end
      answer = questdlg('Satisfied?');
      if ~strcmp(answer,'Yes')
        delete(htry);
        return
      end
      pfx_b = polyfit(x(fitrange_b),f(fitrange_b),1);
      pfx_a = polyfit(x(fitrange_a),f(fitrange_a),1);
      % Create new rip/zip struct
      new.range = [rng(1),rng(end)];
      new.force = polyval(pft_b,t(ix));
      new.time = t(ix);
      new.ripx = x(ix);
      xend = (new.force-pfx_a(2))/pfx_a(1);
      new.deltax = xend - new.ripx;
      new.fdot = pft_b(1);
      rip_index = ix - rng(1) +1;
      new.fstep = polyval(pft_b,t(rip_index))-polyval(pft_a,t(rip_index));
      new.rip_index = rip_index;
      new.pfx_b = pfx_b;
      new.pfx_a = pfx_a;
      new.dt = mean(diff(t(rng)));
      new.temperature = app.filedata.T(ix);     
      new.noise = std(f(fitrange_b)-polyval(pft_b,t(fitrange_b)));
      new.fitrange = [fitrange_b(1),fitrange_a(end)] -rng(1); 
      new.topforce = max(f(rng));
      new.pullingspeed = median(diff(x(rng))./diff(t(rng)));
      new.filename = string(app.Datafile);
      new.cycleno = app.cycleno;
      new.work = Crooks_work(new.force,new.deltax,new.temperature,app.par);
      % new.t = t(rng);
      % new.f = f(rng);
      % new.x = x(rng);
      % new.T = app.filedata.T(rng);
    end


    function SelectCycle(app)
      k = app.CyclenoSpinner.Value;
      % t = app.filedata.t;
      % f = app.filedata.f;
      % x = app.filedata.x;
      peakpos = app.filedata.peakpos;
      valleypos = app.filedata.valleypos;       
      pullfirst = app.PullrelaxButton.Value;
      if pullfirst
        kmax = numel(valleypos)-1;
      else
        kmax = numel(peakpos) -1;
      end
      if k < 1
        k = 1;
      elseif k > kmax
        k = kmax;
      end
      app.CyclenoSpinner.Value = k;
      app.allcyclesLabel.Text = sprintf('of %d',kmax);
      app.cycleno = k;     
      % mark_cycle(app.UIAxes3,k,peakpos,valleypos,pullfirst); 
      mark_cycle(app,pullfirst);
      hold(app.UIAxes4,"off");
      plot_cycle(app,app.UIAxes4,k);
    end
    
    function plot_cycle(app,ax,k)     
    % Plot force f vs trap position x for connected pull and relax traces   
      t = app.filedata.t;
      f = app.filedata.f;
      x = app.filedata.x;
      pullno = [];
      relaxno = [];
      peakpos = app.filedata.peakpos;
      valleypos = app.filedata.valleypos;       
      pullfirst = app.PullrelaxButton.Value;

      % riptimes = vertcat(app.traces.pull.time);
      % ziptimes = vertcat(app.traces.relax.time);    
      peakfirst = valleypos(1)>peakpos(1);  
      labels1 = [app.Trace1Label,app.Event1Label,app.t11Label,app.t12Label,app.t13Label,app.f1Label,app.dx1Label];
      labels2 = [app.Trace2Label,app.Event2Label,app.t21Label,app.t22Label,app.t23Label,app.f2Label,app.dx2Label];
      for i = 1:7
        labels1(i).Text = '';
        labels2(i).Text = '';
      end

      % if ~isempty(app.traces.pull.time)
      if pullfirst
        pulllabels = labels1;
        relaxlabels = labels2;
        % if peakfirst
          pullrange = app.cyclerange(1:2);
          relaxrange = app.cyclerange(2:3) + [1,0];
          pullrng = pullrange(1):pullrange(2);
          relaxrng = relaxrange(1):relaxrange(2);
          % pullrng = valleypos(k):peakpos(k+1);
          % relaxrng = peakpos(k+1)+1:valleypos(k+1);
          % pullno = find(t(valleypos(k))<riptimes & riptimes < t(peakpos(k+1)),1);
          % relaxno = find(t(peakpos(k+1))<ziptimes & ziptimes < t(valleypos(k+1)),1); %OK
        % else
        %   pullrange = [app.cyclerange(2)+1,app.cyclerange(3)];
        %   relaxrange = app.cyclerange(1:2);
        %   pullrng = valleypos(k):peakpos(k);
        %   relaxrng = peakpos(k)+1:valleypos(k+1);      
        %   % pullno = find(t(valleypos(k))<riptimes & riptimes < t(peakpos(k)),1);
        %   % relaxno = find(t(peakpos(k))<ziptimes & ziptimes < t(peakpos(k+1)),1);          
        % end   
      else
        pulllabels = labels2;
        relaxlabels = labels1;   
        pullrange = app.cyclerange(2:3) +[1,0];
        relaxrange = app.cyclerange(1:2);
        % if peakfirst
        %   relaxrng = peakpos(k)+1:valleypos(k);
        %   pullrng = valleypos(k):peakpos(k+1);  
        %   % pullno = find(t(valleypos(k))<riptimes & riptimes < t(peakpos(k+1)),1);
        %   % relaxno = find(t(peakpos(k))<ziptimes & ziptimes < t(valleypos(k)),1);          
        % else
        %   relaxrng = peakpos(k)+1:valleypos(k+1);  
        %   pullrng = valleypos(k+1):peakpos(k+1);    
        %   % pullno = find(t(valleypos(k+1))<riptimes & riptimes < t(peakpos(k+1)),1);
        %   % relaxno = find(t(peakpos(k))<ziptimes & ziptimes < t(valleypos(k+1)),1);     %OK      
        % end

      end
      pullrng = pullrange(1):pullrange(2);
      relaxrng = relaxrange(1):relaxrange(2);

      cla(ax,'reset');
      zoom(ax,"on");
      % Reset h2 and HideTracesButtonGroup
      app.h2 = zeros(2,4);
      app.RelaxButton.Value = 0;
      app.PullButton.Value = 0;
      app.BothButton.Value = 1;

      app.h2(1,1) = plot(ax,x(pullrng),f(pullrng),'r');
      hold(ax,"on");
      pulllabels(1).Text="Pulling trace";
      pulllabels(3).Text=sprintf('%.1fs',t(pullrng(1)));
      pulllabels(5).Text=sprintf('%.1fs',t(pullrng(end)));
      if ~isempty(app.traces.pull)
        % Fill in rip propertes:
        riptimes = vertcat(app.traces.pull.time);
        % if pullfirst
        if peakfirst
          pullno = find(t(valleypos(k))<riptimes & riptimes < t(peakpos(k+1)),1);   % k,k+1
        else
          if pullfirst
            pullno = find(t(valleypos(k))<riptimes & riptimes < t(peakpos(k)),1);     % k,k
          else
            pullno = find(t(valleypos(k+1))<riptimes & riptimes < t(peakpos(k+1)),1); % k+1, k+1
          end
        end
        if ~isempty(pullno)
          pull = app.traces.pull(pullno);
          startx = x(pullrng(1));
          endx = x(pullrng(end));
          ripx = pull.ripx;        
          fitlength = (endx-startx)/10;
          fitx = [max(startx,ripx-fitlength),min(endx,ripx+fitlength)];
          app.h2(1,2:4) = plot(ax,ripx+[0,pull.deltax],pull.force+[0,0],'-ok', ...
            fitx,polyval(pull.pfx_b,fitx),'k', ...
            fitx,polyval(pull.pfx_a,fitx),'k');
  
          pulllabels(2).Text="Rip";
          pulllabels(3).Text=sprintf('%.1fs',app.filedata.t(pullrange(1)));
          pulllabels(4).Text=sprintf('%.1fs',pull.time);
          pulllabels(5).Text=sprintf('%.1fs',app.filedata.t(pullrange(2)));
          pulllabels(6).Text=sprintf('%.1fpN',pull.force);
          pulllabels(7).Text=sprintf('%.1fnm',pull.deltax);
        end
      end

      relaxlabels(1).Text="Relaxing trace";
      relaxlabels(3).Text=sprintf('%.1fs',t(relaxrng(1)));
      relaxlabels(5).Text=sprintf('%.1fs',t(relaxrng(end)));
      % To make sure the pull and relax x values match at high force:
      % shift x values for the relax trace by xshift
      xshift = x(pullrng(end))-x(relaxrng(1));
      xrelax = x(relaxrng) + xshift;
      % plot(ax,x(relaxrng),f(relaxrng),'b'); 
      app.h2(2,1) = plot(ax,xrelax,f(relaxrng),'b');
      if ~isempty(app.traces.relax)
        ziptimes = vertcat(app.traces.relax.time);
        if peakfirst
          if pullfirst
            relaxno = find(t(peakpos(k+1))<ziptimes & ziptimes < t(valleypos(k+1)),1);  % k+1, k+1
          else
            relaxno = find(t(peakpos(k))<ziptimes & ziptimes < t(valleypos(k)),1);       % k, k
          end
        else
          relaxno = find(t(peakpos(k))<ziptimes & ziptimes < t(peakpos(k+1)),1);       % k, k+1
        end

        if ~isempty(relaxno)
          relax = app.traces.relax(relaxno);
          startx = x(relaxrng(1))+ xshift;
          endx = x(relaxrng(end)) + xshift;
          ripx = relax.ripx + xshift;
          fitlength = (startx-endx)/10;
          fitx = [min(ripx+fitlength,startx),max(ripx-fitlength,endx)];
          % plot(ax,ripx,relax.force,'ok',ripx+[0,relax.deltax], ...
          app.h2(2,2:4) = plot(ax,ripx+[0,relax.deltax],relax.force+[0,0],'-ok', ...
            fitx+xshift,polyval(relax.pfx_b,fitx),'k', ...
            fitx+xshift,polyval(relax.pfx_a,fitx),'k');
          relaxlabels(2).Text="Zip";
          relaxlabels(3).Text=sprintf('%.1fs',t(relaxrange(1)));
          relaxlabels(4).Text=sprintf('%.1fs',relax.time);
          relaxlabels(5).Text=sprintf('%.1fs',t(relaxrange(2))); 
          relaxlabels(6).Text=sprintf('%.1fpN',relax.force);
          relaxlabels(7).Text=sprintf('%.1fnm',relax.deltax); 
        end
      end
      xlabel(ax,'Trap x (nm)')
      ylabel(ax,'Force (pN)')
      box(ax,"on");

      zoom(ax,"on");
      % hold(ax,"off");
      for k = 1:8
        if app.h2(k) ~= 0
          set(app.h2(k),'Visible','on');
        end
      end       
    end
    
    function plot_traces(app,ax,klist,shift)
      t = app.filedata.t;
      f = app.filedata.f;
      x = app.filedata.x;
      peakpos = app.filedata.peakpos;
      valleypos = app.filedata.valleypos;  
      peakfirst = valleypos(1)>peakpos(1); 
      cla(ax);
      hold(ax,'on')
      for i = 1:numel(klist)
        if peakfirst
          pullrng = valleypos(klist(i)):peakpos(klist(i)+1);
          relaxrng = peakpos(klist(i)+1)+1:valleypos(klist(i)+1);
        else
          pullrng = valleypos(klist(i)):peakpos(klist(i));
          relaxrng = peakpos(klist(i))+1:valleypos(klist(i)+1);              
        end
        % Make sure x values match at top
        xpull = x(pullrng);
        xrelax = x(relaxrng);
        xrelax = xrelax + xpull(end)-xrelax(1);
        plot(ax,xpull+shift*(i-1),f(pullrng),'r');
        plot(ax,xrelax+shift*(i-1),f(relaxrng),'b');
      end
      xlabel(ax,'x');ylabel(ax,'Force');
      tstart = t(valleypos(klist(1)));
      tstop = t(valleypos(klist(i)+1));
      title(ax,sprintf('%.1fs < t < %.1fs',tstart,tstop))
    end
    
    function plot_many_cycles(app)
      t0 = app.StarttimeEditField.Value;
      if t0 < app.filedata.t(1)
        t0 = app.filedata.t(1);
      end
      maxpos = app.filedata.valleypos(end-app.NumberofcyclesEditField.Value);
      if t0 > app.filedata.t(maxpos)
        t0 = app.filedata.t(maxpos);
      end
      [~,startcycle] = min(abs(t0-app.filedata.t(app.filedata.valleypos)));
      pos1 = app.filedata.valleypos(startcycle);
      t1 = app.filedata.t(pos1);
      app.StarttimeEditField.Value = t1;
      no_cycles = app.NumberofcyclesEditField.Value;
      lastvalley = min(startcycle + no_cycles,numel(app.filedata.valleypos));
      pos2 = app.filedata.valleypos(lastvalley);
      t2 = app.filedata.t(pos2);
      if isfield(app.h3,'region')
        delete(app.h3.region);
      end
      app.h3.region = xregion(app.UIAxes6,t1,t2);
      shift = app.CyclespacingnmEditField.Value;
      plot_traces(app,app.UIAxes5,startcycle:lastvalley-1,shift); 
      app.StarttimeEditField.Value = t0;
      
    end
    
    function [pull,relax] = table2trace(app,Trip,Tzip)
    % Creates tracees struct array for current file from Trip and Tzip
      pull = empty_trace;
      for row = 1:height(Trip)
        pull(row) = tablerow2trace(app,Trip,row,1);
      end
      relax = empty_trace;
      for row = 1:height(Tzip)
        relax(row) = tablerow2trace(app,Tzip,row,-1);
      end
    end
   
    function s = tablerow2trace(app,T,row,sgn)
    % Create a trace struct for the current file from a results table row
  
      ix = find(app.filedata.t == T.Time(row));
      lastvalley = find(app.filedata.valleypos<ix,1,'last');
      lastvalleyix = app.filedata.valleypos(lastvalley);
      if isempty(lastvalley)
        lastvalley = 0;
        lastvalleyix = 1;
      end
      if lastvalley < length(app.filedata.valleypos)
        nextvalleyix = app.filedata.valleypos(lastvalley+1);
        [~,xmaxpos] = max(app.filedata.x(lastvalleyix:nextvalleyix));
      else
        [~,xmaxpos] = max(app.filedata.x(lastvalleyix:end));
      end
      xmaxix = lastvalleyix + xmaxpos - 1;  

      if sgn > 0  % Pulling trace 
        if xmaxix > ix  % Normal rip
            trace_start = lastvalleyix;
            trace_stop = xmaxix;
          else
            trace_start = xmaxix;
            trace_stop = nextvalleyix;
        end
        else  % Late rip
          trace_start = xmaxix+1;
          trace_stop = nextvalleyix;
      end
      f = app.filedata.f(trace_start:trace_stop);
      x = app.filedata.x(trace_start:trace_stop);
      s.range = valid_trace_part(f,sgn,app.par);
      s.force = T.Force(row);
      s.deltax = T.Deltax(row);
      s.ripx = T.Trapx(row);
      s.time = T.Time(row);
      s.fstep = T.Forceshift(row);
      s.rip_index = ix - trace_start + 1;
      s.fdot = T.Fdot(row);

      % fitting range before rip/zip
      nf = numel(s.range);
      fitb_start = max(s.rip_index - round(app.par.maxfitfraction*nf),1);
      fitrange_b = fitb_start:s.rip_index;
      s.pfx_b = polyfit(x(fitrange_b),f(fitrange_b),1);  
      % fitting range after rip/zip
      fita_start = s.rip_index+2*app.par.ripsteps;
      fita_stop = min(fita_start+round(app.par.maxfitfraction*nf),s.range(end));
      fitrange_a = fita_start:fita_stop;
      s.pfx_a = polyfit(x(fitrange_a),f(fitrange_a),1); 

      s.dt = T.Timestep(row);
      s.temperature = T.Temperature(row);
      s.noise = T.Noise(row); 
      s.fitrange = T.Fitrange(row,:);
      s.topforce = T.Topforce(row);
      s.pullingspeed = T.Pullingspeed(row);
      s.filename = T.Filename(row);
      s.cycleno = T.Cycleno(row);
      s.work = T.Work(row);      
    end
    
    function filefound = recall_changes(app)
      outputfile = fullfile(app.Outputfolder, ...
      strrep(app.Datafile,".txt",".mat"));
      filefound = exist(outputfile,'file');
      if filefound
        if ~strcmp(app.filedata.filename,app.Datafile)
          read_experiment(app);
        end
        load(outputfile,'Trip','Tzip','limits');
        [app.traces.pull,app.traces.relax] = table2trace(app,Trip,Tzip);
        app.filedata.limits = limits;
        ok = app.filedata.peakpos>app.filedata.limits(1) & ...
          app.filedata.peakpos<app.filedata.limits(2);
        app.filedata.peakpos = app.filedata.peakpos(ok);
        ok = app.filedata.valleypos>app.filedata.limits(1) & ...
          app.filedata.valleypos<app.filedata.limits(2);
        app.filedata.valleypos = app.filedata.valleypos(ok);       
        app.ChangeStatusButton.Text = "Revert to automatic analysis";
        plot_file(app,app.UIAxes)
        app.Status = "Corrected";
        app.StatusLabel.Text = app.Status;
      else
        app.Status = "No file";
      end
    end
    
    function results = mark_cycle(app,pullfirst)
      ax = app.UIAxes3;
      if isfield(ax.UserData,'region')
        delete(ax.UserData.region)  % remove existing region
      end
      t = app.filedata.t;
      x = app.filedata.x;
      k = app.cycleno;

      if pullfirst
        rng = app.filedata.valleypos(k):app.filedata.valleypos(k+1);
        if k < numel(app.filedata.valleypos)
          [~,pkpos] = max(x(rng));
          pkix = pkpos + rng(1) -1;
          app.cyclerange = [rng(1),pkix,rng(end)];
          t1 = t(app.filedata.valleypos(k));
          t2 = t(app.filedata.valleypos(k+1));
        else
          return;
        end
      else
        rng = app.filedata.peakpos(k):app.filedata.peakpos(k+1);
        [~,vlpos] = min(x(rng));
        vlix = vlpos + rng(1) -1;
        app.cyclerange = [rng(1),vlix,rng(end)];
        if k < numel(app.filedata.peakpos)
          t1 = t(app.filedata.peakpos(k));
          t2 = t(app.filedata.peakpos(k+1));
        else
          return;
        end
      end
      region = xregion(ax,t1,t2);
      ax.UserData.region = region;
    end
  end

%*****************************************************************'''  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, riplabels)
      app.UIFigure.Name = 'Rip analysis';
      app.par = params;
      app.settings = [];

      if exist("RipAnalysis_settings.mat",'file')
        load RipAnalysis_settings appsettings
        app.settings = appsettings;
      else
        return
      end
      field_present(1) = isfield( appsettings,'Datafolder');
      field_present(2) = isfield( appsettings,'Outputfolder');
      field_present(3) = isfield( appsettings,'Datafile');

      if field_present(1) && exist(appsettings.Datafolder,"dir")
        app.Datafolder =  strrep(appsettings.Datafolder,'\','/');
        app.DatafolderTextArea.Value = app.Datafolder;
        app.StatusLabel.Visible = "off";
      end

      if field_present(2) % &  exist(app.Outputfolder,"dir")
        app.Outputfolder = appsettings.Outputfolder;
        app.OutputfolderTextArea.Value = app.Outputfolder;
      end
      
      if field_present(3)
        % fields_ok = fields_ok & ~isempty(appsettings.Datafile);
        % fields_ok = fields_ok & exist(fullfile(appsettings.Datafolder, ...
        %   appsettings.Datafile),"file");
        app.Datafile =  appsettings.Datafile;
        app.DatafileTextArea.Value = app.Datafile;
        if sum(ismember(fieldnames(appsettings),"Fileno")) > 0
          app.fileno = appsettings.Fileno;
        else
          app.fileno = 1;
        end
        app.Datafile = string(app.DatafileTextArea.Value);
        if field_present(2)
          app.filedata = [];
          read_experiment(app);
          if isempty(app.traces.pull)
            uialert(app.UIFigure,'No episodes found in file','Bad file')
            return
          end
        end
        app.StatusLabel.Visible = "on";
        filefound = recall_changes(app);
        if ~filefound
          read_experiment(app);
          plot_file(app,app.UIAxes);
          app.StatusLabel.Text = app.Status;
          app.ChangeStatusButton.Text = "No saved results";
        end 
        plot_file(app,app.UIAxes)      
      end

      if exist(fullfile(app.Datafolder,"Filelist.m"),"file")
        app.UseFilelistmCheckBox.Visible = 'on';
      end        
    end

    % Button pushed function: SelectfolderButton
    function SelectfolderButtonPushed(app, event)
      folder = uigetdir(app.Datafolder);
      if folder == 0
        return   % User pressed "Cancel"
      end
      folder = strrep(folder,'\','/');  % Mac/Unix format
      app.Datafolder = folder;
      app.settings.Datafolder = folder;
      appsettings = app.settings;
       % Set Outputfoldeer to the same folder as default:
      app.Outputfolder = folder;
      app.settings.Outputfolder = folder;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
      app.DatafolderTextArea.Value = app.Datafolder;
      app.OutputfolderTextArea.Value = app.Outputfolder;
      if exist(fullfile(app.Datafolder,"Filelist.m"),"file")
        app.UseFilelistmCheckBox.Visible = 'on';
      else
        app.UseFilelistmCheckBox.Visible = 'off';
        app.Label.Visible = "off";
      end       
    end

    % Button pushed function: SelectfileButton
    function SelectfileButtonPushed(app, event)
      % Read current datafile parts
      subfolder = '';
      filename = string(app.DatafileTextArea.Value);
      % if iscell(filename)  % Selected using the 'Select file' button
      %   filename = filename{1};
      % end
      if isfile(fullfile(app.Datafolder,filename))
        fileparts = split(filename,'/');
        if numel(fileparts) > 1
          subfolder = [fileparts{1},'/'];
        end
        [file,path] = uigetfile(strcat(app.settings.Datafolder,"/",subfolder,"/*.txt"));
      else
        app.DatafileTextArea.Value = '';
        path = uigetdir(app.Datafolder);
        if path ~= 0
          path = strrep(path,"\","/")
          pathparts = strsplit(path,"/");
          subfolder = string(pathparts(end));
          [file,path] = uigetfile(strcat(path,"/*.txt"));
          % if file == 0
          %   return  % User pressed "Cancel"
          % else
            app.Datafile = strcat(subfolder,"/",file);
            app.DatafileTextArea.Value = app.Datafile;
          % end
        end
      end 
      % [~,~,ext] = fileparts(string(app.Datafile));
      % switch ext
      %   case ".m"
      %     run(fullfile(path,file));
      %     app.filelist = list_of_files;
        % case ".txt"
          % Ensure path is a character array with Unix/Mac delimiters (/)
        path = char(path);
        path = strrep(path,'\','/');  
        % Find element (from end) where path and app.datafolder differ
        k = strfind(flip(path),flip(app.Datafolder));
        if isempty(k)
          msgbox('To select a file from a different data folder, Change the Data Dolder first')
          return
        end
        shortname = strcat(path(end-k+3:end),file); 
        file = shortname;
        app.Datafile = file;
        app.settings.Datafile = file;      % For storing in RipAnalysis_settings
        app.DatafileTextArea.Value = app.Datafile; % Show name in app
        appsettings = app.settings;
        save RipAnalysis_settings appsettings
        cla(app.UIAxes);  
        read_experiment(app);
        if isempty(app.traces.pull)
          % uialert(app.UIFigure,'No rips found in file','Bad file')
          return
        end
        plot_file(app,app.UIAxes);
    end

    % Button pushed function: RestoreviewButton
    function RestoreviewButtonPushed(app, event)
      app.RemoveripszipsButton.Visible = "on";
      app.SaveButton.Visible = "on";
      app.UIAxes.Visible = "on";
      app.ChangeStatusButton.Visible = "on";
      app.yaxisButtonGroup.Visible = "on";
      app.AddripzipButton.Visible = "on";
      % app.InspectripzipButton.Visible = "on";
      app.RemoveintervalLabel.Visible = "on";
      app.AtendButton.Visible = "on";
      app.AtbeginningButton.Visible = "on";

      % if numel(app.h)>2 & isfield(app.h(3),'legend')
      %   delete(app.h(3).legend)
      % end
      % cla(app.UIAxes2);
      % app.UIAxes2.Visible = "off";
      % app.UIAxes2.Toolbar.Visible = "off";
      app.UIAxes.Visible = "on";
      % axtoolbar(app.UIAxes2);  % remove axis toolbar
      axtoolbar(app.UIAxes,{'default'});
      plot_file(app,app.UIAxes);
    end

    % Button pushed function: RemoveripszipsButton
    function RemoveripszipsButtonPushed(app, event)
      [localrips,localzips,visiblerips,visiblezips] = ...
        selectrip(app,'Multiple');
      rips = visiblerips(localrips);
      zips = visiblezips(localzips);
      if isempty(localrips) & isempty(localzips)
        return
      end
      txt = '';
      if ~isempty(localrips)
        txt = 'rips ';
      end
      for rno = 1:numel(localrips)
        txt = [txt, num2str(localrips(rno)), ' '];
      end
      if ~isempty(txt) & ~isempty(zips)
        txt = [txt,'and'];
      end
      if ~isempty(localzips)
        txt = [txt,' zips '];
      end
      for rno = 1:numel(localzips)
        txt = [txt, num2str(localzips(rno)), ' '];
      end
      quest = ['Do you really want to remove ',txt,'?'];
      answer = questdlg(quest);

      if answer == "Yes"
        % Delete trace structs
        for i = 1:numel(rips)
          app.traces.pull(visiblerips(localrips(i))) = [];
        end
        for i = 1:numel(localzips)
          app.traces.relax(visiblezips(localzips(i))) = [];
        end
        cla(app.UIAxes);
        plot_file(app,app.UIAxes);
      end
    end

    % Callback function
    function RecallsavedchangesButtonPushed(app, event)

      outputfile = fullfile(app.Outputfolder, ...
        strrep(app.Datafile,".txt",".mat"));
      if ~exist(outputfile,'file')
        msgbox(sprintf("Results file %s not found",outputfile))
        return
      end
      load(outputfile,'Trip','Tzip','limits');
      if isempty(Trip)
         msgbox(sprintf('Outputfile %s not found',outputfile));
      end

      [app.traces.pull,app.traces.relax] = table2trace(app,Trip,Tzip);
      app.filedata.limits = limits;
      ok = app.filedata.peakpos>app.filedata.limits(1) & ...
        app.filedata.peakpos<app.filedata.limits(2);
      app.filedata.peakpos = app.filedata.peakpos(ok);
      ok = app.filedata.valleypos>app.filedata.limits(1) & ...
        app.filedata.valleypos<app.filedata.limits(2);
      app.filedata.valleypos = app.filedata.valleypos(ok);       
      plot_file(app,app.UIAxes)
    end

    % Button pushed function: SaveButton
    function SaveButtonPushed(app, event)
      % msgbox('The results will be saved as Matlab tables Trip and Tzip')
      if ~isfield(app.settings,'Outputfolder')
        waitfor(msgbox('Please select a folder for output files'));
        folder = strrep(uigetdir,'\','/');  % Convert to Unix format
        if folder == 0
          return   % User pressed "Cancel"
        end
        app.Outputfolder = folder;
        app.settings.Outputfolder = folder;
        appsettings = app.settings;
        save RipAnalysis_settings appsettings
      end
      Trip = create_table(app.traces.pull);  % Rip table
      Tzip = create_table(app.traces.relax);  % Zip table
      parts = strsplit(app.Datafile,"/");
      nparts = numel(parts);
      folder = string(app.Outputfolder);
      if nparts > 1
        for i = 1:nparts-1
          folder = fullfile(folder,parts(i));
          if ~exist(folder,"dir")
            mkdir(folder);
          end
        end
      end
      matfile = fullfile(app.Outputfolder,strrep(app.Datafile,'.txt','.mat'));
      excelfile = fullfile(app.Outputfolder,strrep(app.Datafile,'.txt','.xlsx'));
      limits = app.filedata.limits;  % Axis limits
      save(matfile,'Trip','Tzip','limits');
      msgtext = sprintf("Results tables saved to %s",matfile);
      app.Status = "Corrected";
      % try
      %   writetable(Trip,excelfile,'Sheet','Rips');
      %   writetable(Tzip,excelfile,'Sheet','Zips');
      %   msgtext(2) = sprintf("Results tables saved to %s",excelfile);
      % catch
      %   msgtext(2) = sprintf('UNABLE TO WRITE to %s',excelfile);
      % end  
      msgbox(msgtext);
      app.ChangeStatusButton.Text = "Revert to automatic analysis"
    end

    % Value changed function: DatafolderTextArea
    function DatafolderTextAreaValueChanged(app, event)
      folder = app.DatafolderTextArea.Value;
      if iscell(folder)
        folder = folder{1};
      end
      if ismember('\',folder)
        folder = strrep(folder,'\','/');
        app.DatafolderTextArea.Value = folder;
      end
      app.Datafolder = folder;
      app.settings.Datafolder = folder;
      % Set Outputfoldeer to the same folder as default:
      app.OutputfolderTextArea.Value = folder;
      app.Outputfolder = folder;
      app.settings.Outputfolder = folder;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
      if exist(fullfile(app.Datafolder,"Filelist.m"),"file")
        app.UseFilelistmCheckBox.Visible = 'on';
        app.Label.Text = sprintf('of %d',length(app.filelist));
        app.Label.Visible = "on";
      else
        app.UseFilelistmCheckBox.Visible = 'off';
        app.FilelistindexSpinner.Visible = 'off';
        app.FilelistindexLabel.Visible = 'off';
        app.Label.Visible = "off";
      end         
    end

    % Value changed function: DatafileTextArea
    function DatafileTextAreaValueChanged(app, event)
      file = app.DatafileTextArea.Value;
      if iscell(file)
        file = file{1};
      end
      if isempty(file)
        return
      end
      if isprop(app,'filelist')
        nfile = find(app.filelist == file);
        if ~isempty(nfile)
          app.fileno = nfile;
        end
        if app.UseFilelistmCheckBox.Value
          app.FilelistindexSpinner.Value = app.fileno;
        end
      end
      app.settings.Datafile = file;
      app.Status = "automatic";
      app.StatusLabel.Text = app.Status;
      app.Datafile = file;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
      read_experiment(app);
      plot_file(app,app.UIAxes);
      
    end

    % Button pushed function: SelectfolderButton_2
    function SelectfolderButton_2Pushed(app, event)
      folder = uigetdir(app.Outputfolder);
      if folder == 0
        return   % User pressed "Cancel"
      end
      app.Outputfolder = strrep(folder,'\','/');
      app.settings.Outputfolder = folder;
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
      app.OutputfolderTextArea.Value = app.Outputfolder;
    end

    % Selection changed function: xaxisButtonGroup
    function xaxisButtonGroupSelectionChanged(app, event)
      limx = xlim(app.UIAxes);
      if app.RecordButton.Value
        % Find record no range from plot t limits
        [~,r1] = min(abs(app.filedata.t-limx(1)));
        [~,r2] = min(abs(app.filedata.t-limx(2)));
        plot_file(app,app.UIAxes,[r1,r2]);
      else
        % Find t range from record plot range
        plot_file(app,app.UIAxes,app.filedata.t(limx));
      end      
      % plot_file(app,app.UIAxes);
    end

    % Button pushed function: AtbeginningButton
    function AtbeginningButtonPushed(app, event)
      setlimit(app,'Start');
    end

    % Button pushed function: AtendButton
    function AtendButtonPushed(app, event)
      setlimit(app,'End')
    end

    % Button pushed function: AddripzipButton
    function AddripzipButtonPushed(app, event)
      % ***********************************************
      % Cast magic spell to make ginput crosshairs apppear in App window:
      fhv = app.UIFigure.HandleVisibility;        % Current state
      app.UIFigure.HandleVisibility = 'callback'; % Temporarily make UIFigure handle visible outside app
      set(0, 'CurrentFigure', app.UIFigure);
      answer = 'No';
      % while strcmp(answer,'No')
        [answer,new,sgn] = tryrip(app);
        if isempty(new)
          return
        end
      % end
      app.UIFigure.HandleVisibility = fhv;
      % Magic spell lifted
      % ***********************************************
      if ~strcmp(answer,'Yes')  % User selected 'Cancel'
        return
      end
      % New rip/zip accepted. Insert in app.traces
      if sgn > 0  % Pull trace
        if isempty(app.traces.pull)
          app.traces.pull = new;
        else
          times = vertcat(app.traces.pull.time);
          if new.time < times(1)
            app.traces.pull = [new;app.traces.pull];
          else
            index = find(times>new.time,1);
            if isempty(index)  % add new at end
              app.traces.pull = [app.traces.pull,new];
            else
              ntraces = length(times);
              app.traces.pull(index+1:ntraces+1) = app.traces.pull(index:ntraces);
              app.traces.pull(index) = new;
            end
          end
        end
      else
        if isempty(app.traces.relax)
          app.traces.relax = new;
        else
          times = vertcat(app.traces.relax.time);
          if new.time < times(1)
            app.traces.relax = [new;app.traces.relax];
          else
            index = find(times>new.time,1);
            if isempty(index)  % add new at end
              app.traces.relax = [app.traces.relax,new];
            else
              ntraces = length(times);
              app.traces.relax(index+1:ntraces+1) = app.traces.relax(index:ntraces);
              app.traces.relax(index) = new;
            end
          end
        end
      end
      limx = xlim(app.UIAxes);
      plot_file(app,app.UIAxes);
      xlim(app.UIAxes,limx);
    end

    % Selection changed function: yaxisButtonGroup
    function yaxisButtonGroupSelectionChanged(app, event)
      % selectedButton = app.yaxisButtonGroup.SelectedObject;
      limx = xlim(app.UIAxes);
      plot_file(app,app.UIAxes,limx);
    end

    % Selection change function: TabGroup
    function TabGroupSelectionChanged(app, event)
      selectedTab = app.TabGroup.SelectedTab;
      if selectedTab.Title == "Single f vs x traces"
        plot_file(app,app.UIAxes3)
        app.CyclenoSpinner.Value = 1;
        SelectCycle(app);
      end
      if selectedTab.Title == "Multiple f vs x traces"
        plot_file(app,app.UIAxes6)
        plot_many_cycles(app)
      end      
    end

    % Value changed function: CyclenoSpinner
    function CyclenoSpinnerValueChanged(app, event)
      % linevis = app.CyclenoSpinner.Value;
      SelectCycle(app)
    end

    % Selection changed function: CycletypeButtonGroup
    function CycletypeButtonGroupSelectionChanged(app, event)
      % selectedButton = app.CycletypeButtonGroup.SelectedObject;
      SelectCycle(app)
    end

    % Button pushed function: ExportfigureButton
    function ExportfigureButtonPushed(app, event)
      limx = xlim(app.UIAxes);
      limy = ylim(app.UIAxes);
      figure;
      plot_file(app,gca)
      xlim(limx);
      ylim(limy);
    end

    % Button pushed function: ExportfigureButton_2
    function ExportfigureButton_2Pushed(app, event)
      % 1. Create a standard external figure and regular axes
      newFig = figure;
      newAxes = axes(newFig);
      
      % 2. Copy all chart objects/plots from the App Designer UIAxes
      copyobj(app.UIAxes4.Children, newAxes);
      
      % 3. Copy visual properties manually to retain axes styling
      newAxes.XLim = app.UIAxes4.XLim;
      newAxes.YLim = app.UIAxes4.YLim;
      newAxes.XLabel.String = app.UIAxes4.XLabel.String;
      newAxes.YLabel.String = app.UIAxes4.YLabel.String;
      newAxes.Title.String  = app.UIAxes4.Title.String;  
      t = app.filedata.t;
      peakpos = app.filedata.peakpos;
      valleypos = app.filedata.valleypos;       
      pullfirst = app.RelaxpullButton.Value;
      k = app.CyclenoSpinner.Value;
      if pullfirst
        if k < numel(valleypos)
          t1 = t(valleypos(k));
          t2 = t(valleypos(k+1));
        else
          return;
        end
      else
        if k < numel(peakpos)
          t1 = t(peakpos(k));
          t2 = t(peakpos(k+1));
        else
          return;
        end
      end      
      title(sprintf('%s   %.1fs< t <%.1fs',app.Datafile,t1,t2))
      % k = app.CyclenoSpinner.Value;
      % t = app.filedata.t;
      % % f = app.filedata.f;
      % % x = app.filedata.x;
      % peakpos = app.filedata.peakpos;
      % valleypos = app.filedata.valleypos;       
      % pullfirst = app.RelaxpullButton.Value;
      % 
      % % peakfirst = valleypos(1)>peakpos(1); 
      % if pullfirst
      %   if k < numel(valleypos)
      %     t1 = t(valleypos(k));
      %     t2 = t(valleypos(k+1));
      %   else
      %     return;
      %   end
      % else
      %   if k < numel(peakpos)
      %     t1 = t(peakpos(k));
      %     t2 = t(peakpos(k+1));
      %   else
      %     return;
      %   end
      % end
      % 
      % limx = xlim(app.UIAxes4);
      % limy = ylim(app.UIAxes4);
      % figure;
      % plot_cycle(app,gca,k);
      % xlim(limx);
      % ylim(limy);
      % title(sprintf('%s   %.1fs< t <%.1fs',app.Datafile,t1,t2))
    end

    % Value changed function: OutputfolderTextArea
    function OutputfolderTextAreaValueChanged(app, event)
      value = app.OutputfolderTextArea.Value;
      app.Outputfolder = value{1};
      app.settings.Outputfolder = value{1};
      appsettings = app.settings;
      save RipAnalysis_settings appsettings
    end

    % Selection changed function: ShowtracesButtonGroup
    function ShowtracesButtonGroupSelectionChanged(app, event)
      selectedButton = app.ShowtracesButtonGroup.SelectedObject;
      hide_lines = app.HidefittedlinesCheckBox.Value;
      if hide_lines
        linevis = "off";
      else
        linevis = "on";
      end
      switch selectedButton.Text
        case 'Both'
          set(app.h2(1:2,1),'Visible',"on");
          set(app.h2(1:2,2:4),'Visible',linevis);
          % for k = 1:8
          %   if app.h2(k) ~= 0
          %     set(app.h2(k),'Visible','on');
          %   end
          % end
        case 'Pull'
          set(app.h2(1,1),'Visible','on');
          set(app.h2(1,2:4),'Visible',linevis);
          set(app.h2(2,:),'Visible',"off")
          % for k = 1:4
          %   if app.h2(1,k) ~= 0
          %     set(app.h2(1,k),'Visible','on');
          %   end
          %   if app.h2(2,k) ~= 0
          %     set(app.h2(2,k),'Visible','off');
          %   end
          % end
        case 'Relax'
          set(app.h2(2,1),'Visible','on');
          set(app.h2(2,2:4),'Visible',linevis);
          set(app.h2(1,:),'Visible',"off")          
          % for k = 1:4
          %   if app.h2(1,k) ~= 0
          %     set(app.h2(1,k),'Visible','off');
          %   end
          %   if app.h2(2,k) ~= 0
          %     set(app.h2(2,k),'Visible','on');
          %   end
          % end
      end
    end

    % Value changed function: CyclespacingnmEditField
    function CyclespacingnmEditFieldValueChanged(app, event)
      % value = app.CyclespacingnmEditField.Value;
      app.plot_many_cycles;
    end

    % Value changed function: NumberofcyclesEditField
    function NumberofcyclesEditFieldValueChanged(app, event)
      % value = app.NumberofcyclesEditField.Value;
      app.plot_many_cycles;
    end

    % Value changed function: StarttimeEditField
    function StarttimeEditFieldValueChanged(app, event)
      % value = app.StarttimeEditField.Value;
      app.plot_many_cycles;
    end

    % Button pushed function: ExportgraphButton
    function ExportgraphButtonPushed(app, event)
      t0 = app.StarttimeEditField.Value;
      [~,startcycle] = min(abs(t0-app.filedata.t(app.filedata.valleypos)));
      no_cycles = app.NumberofcyclesEditField.Value;
      lastvalley = min(startcycle + no_cycles,numel(app.filedata.valleypos));
      shift = app.CyclespacingnmEditField.Value;
      figure;
      app.plot_traces(gca,startcycle:lastvalley-1,shift);
      ax = gca;
      ttl = ax.Title.String;
      title(sprintf('%s: %s',app.Datafile,ttl))
    end

    % Callback function
    function NextfileButtonPushed(app, event)
      if app.fileno < length(app.filelist)
        app.fileno = app.fileno+1;
        app.Datafile = app.filelist(app.fileno);
        app.DatafileTextArea.Value = app.Datafile;
        app.settings.Datafile = app.Datafile;      % For storing in RipAnalysis_settings
        app.DatafileTextArea.Value = app.Datafile; % Show name in app
        appsettings = app.settings;
        save RipAnalysis_settings appsettings
        cla(app.UIAxes);  
        read_experiment(app);
        if isempty(app.traces.pull)
          % uialert(app.UIFigure,'No rips found in file','Bad file')
          return
        end
        plot_file(app,app.UIAxes);

      end
    end

    % Value changed function: FilelistindexSpinner
    function FilelistindexSpinnerValueChanged(app, event)
      value = app.FilelistindexSpinner.Value;
      if value < 1
        app.FilelistindexSpinner.Value = 1;
      elseif value > length(app.filelist)
        app.FilelistindexSpinner.Value = length(app.filelist);
      else
        app.fileno = value;
        app.Datafile = app.filelist(app.fileno);
        app.DatafileTextArea.Value = app.Datafile;
        app.settings.Datafile = app.Datafile;      % For storing in RipAnalysis_settings
        app.DatafileTextArea.Value = app.Datafile; % Show name in app
        app.FilelistindexSpinner.Value = app.fileno;
        appsettings = app.settings;
        save RipAnalysis_settings appsettings
        cla(app.UIAxes); 
        filefound = recall_changes(app);
        if ~filefound
          read_experiment(app);
          if isempty(app.traces.pull)
            % uialert(app.UIFigure,'No rips found in file','Bad file')
            return
          end
          app.Status = "Automatic";
          app.ChangeStatusButton.Text = "No saved results"; 
        end
        plot_file(app,app.UIAxes);  
        app.StatusLabel.Text = app.Status;
      end
    end

    % Value changed function: UseFilelistmCheckBox
    function UseFilelistmCheckBoxValueChanged(app, event)
      currentfolder = pwd;
      cd(app.Datafolder);
      if ~isfile("Filelist.m")
        msgbox(sprintf("%s/Filelist.m not found",app.Datafolder));
        return
      end
      files = Filelist;
      cd(currentfolder);
      if isempty(app.Datafile)
        app.fileno = 1;
      else
        nfile = find(files == app.Datafile);
        if ~isempty(nfile)
          app.fileno = nfile;
        end
      end
      if app.UseFilelistmCheckBox.Value
        app.filelist = files;
        app.FilelistindexLabel.Visible = "on";
        app.FilelistindexSpinner.Visible = "on";
        app.FilelistindexSpinner.Value = app.fileno;
        app.DatafileTextArea.Value = files(app.fileno);
        app.Label.Text = sprintf('of %d',length(app.filelist));
        app.Label.Visible = "on";
      else
        app.FilelistindexLabel.Visible = "off";
        app.FilelistindexSpinner.Visible = "off";  
        app.Label.Visible = "off";
      end      
    end

    % Button pushed function: ChangeStatusButton
    function ChangeStatusButtonPushed(app, event)
      if strcmp(app.Status,"Automatic")
        filefound = recall_changes(app);
        if filefound
          app.Status = "Corrected";
          app.ChangeStatusButton.Text = "Revert to automatic analysis";
        else
          app.Status = "No file";
          app.ChangeStatusButton.Text = "No corrected data found";
        end
      elseif strcmp(app.Status,"No file")
        read_experiment(app);
        plot_file(app,app.UIAxes);
        app.Status = "Automatic";
        app.ChangeStatusButton.Text = "No saved results";         
      elseif strcmp(app.Status,"Corrected")
        read_experiment(app);
        plot_file(app,app.UIAxes);
        app.Status = "Automatic";
        app.ChangeStatusButton.Text = "Recall corrected results";         
      end
      app.StatusLabel.Text = app.Status;
    end

    % Value changing function: OutputfolderTextArea
    function OutputfolderTextAreaValueChanging(app, event)
      changingValue = event.Value;
      
    end

    % Value changing function: DatafileTextArea
    function DatafileTextAreaValueChanging(app, event)
      changingValue = event.Value;
      
    end

    % Value changed function: HidefittedlinesCheckBox
    function HidefittedlinesCheckBoxValueChanged(app, event)
      hide_lines= app.HidefittedlinesCheckBox.Value;
      selectedButton = app.ShowtracesButtonGroup.SelectedObject;
      if hide_lines
        linevis = "off";
      else
        linevis = "on";
      end
      set(app.h2(1:2,2:4),'Visible',linevis);
      % if linevis 
      %   set(app.h2(1:2,2:4),'Visible','off');
      % else
      %   set(app.h2(1:2,2:4),'Visible','on');
      % end 
      
      switch selectedButton.Text
        case 'Both'
          set(app.h2(1:2,2:4),'Visible',linevis);
        case 'Pull'
          set(app.h2(2,:),'Visible',"off")
        case 'Relax'
          set(app.h2(1,:),'Visible',"off")          
      end      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.TabGroup = uitabgroup(app.UIFigure);
      app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
      app.HomeTab = uitab(app.TabGroup);
      app.HomeTab.Title = 'Home';
      app.UIAxes = uiaxes(app.HomeTab);
      title(app.UIAxes, 'Title')
      xlabel(app.UIAxes, 'X')
      ylabel(app.UIAxes, 'Y')
      zlabel(app.UIAxes, 'Z')
      app.SelectfolderButton = uibutton(app.HomeTab, 'push');
      app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
      app.SelectfolderButton.Text = 'Select folder';
      app.SelectfileButton = uibutton(app.HomeTab, 'push');
      app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
      app.SelectfileButton.Text = 'Select file';
      app.RestoreviewButton = uibutton(app.HomeTab, 'push');
      app.RestoreviewButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreviewButtonPushed, true);
      app.RestoreviewButton.Tooltip = {'Show f(t) for full file'};
      app.RestoreviewButton.Text = 'Restore view';
      app.SaveButton = uibutton(app.HomeTab, 'push');
      app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
      app.SaveButton.Tooltip = {'Save resultstables Trip and Tzip to file'};
      app.SaveButton.Text = 'Save';
      app.SelectfolderButton_2 = uibutton(app.HomeTab, 'push');
      app.SelectfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButton_2Pushed, true);
      app.SelectfolderButton_2.Text = 'Select folder';
      app.AddripzipButton = uibutton(app.HomeTab, 'push');
      app.AddripzipButton.ButtonPushedFcn = createCallbackFcn(app, @AddripzipButtonPushed, true);
      app.AddripzipButton.Tooltip = {'Not implemented'};
      app.AddripzipButton.Text = 'Add rip/zip';
      app.xaxisButtonGroup = uibuttongroup(app.HomeTab);
      app.xaxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @xaxisButtonGroupSelectionChanged, true);
      app.xaxisButtonGroup.Title = 'x axis';
      app.TimesButton = uiradiobutton(app.xaxisButtonGroup);
      app.TimesButton.Text = 'Time (s)';
      app.TimesButton.Value = true;
      app.RecordButton = uiradiobutton(app.xaxisButtonGroup);
      app.RecordButton.Text = 'Record';
      app.AtbeginningButton = uibutton(app.HomeTab, 'push');
      app.AtbeginningButton.ButtonPushedFcn = createCallbackFcn(app, @AtbeginningButtonPushed, true);
      app.AtbeginningButton.Tooltip = {'Default: Beginning'};
      app.AtbeginningButton.Text = 'At beginning';
      app.AtendButton = uibutton(app.HomeTab, 'push');
      app.AtendButton.ButtonPushedFcn = createCallbackFcn(app, @AtendButtonPushed, true);
      app.AtendButton.Tooltip = {'Default: end of file'};
      app.AtendButton.Text = 'At end';
      app.RemoveintervalLabel = uilabel(app.HomeTab);
      app.RemoveintervalLabel.Text = 'Remove interval';
      app.yaxisButtonGroup = uibuttongroup(app.HomeTab);
      app.yaxisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @yaxisButtonGroupSelectionChanged, true);
      app.yaxisButtonGroup.Title = 'y axis';
      app.fButton = uiradiobutton(app.yaxisButtonGroup);
      app.fButton.Text = 'f';
      app.fButton.Value = true;
      app.xButton = uiradiobutton(app.yaxisButtonGroup);
      app.xButton.Text = 'x';
      app.TButton = uiradiobutton(app.yaxisButtonGroup);
      app.TButton.Text = 'T';
      app.DatafolderTextArea_2Label = uilabel(app.HomeTab);
      app.DatafolderTextArea_2Label.Text = 'Data folder';
      app.DatafolderTextArea = uitextarea(app.HomeTab);
      app.DatafolderTextArea.ValueChangedFcn = createCallbackFcn(app, @DatafolderTextAreaValueChanged, true);
      app.DatafileTextArea_2Label = uilabel(app.HomeTab);
      app.DatafileTextArea_2Label.Text = 'Data file';
      app.DatafileTextArea = uitextarea(app.HomeTab);
      app.DatafileTextArea.ValueChangedFcn = createCallbackFcn(app, @DatafileTextAreaValueChanged, true);
      app.DatafileTextArea.ValueChangingFcn = createCallbackFcn(app, @DatafileTextAreaValueChanging, true);
      app.OutputfolderTextArea_2Label = uilabel(app.HomeTab);
      app.OutputfolderTextArea_2Label.Text = 'Output folder';
      app.OutputfolderTextArea = uitextarea(app.HomeTab);
      app.OutputfolderTextArea.ValueChangedFcn = createCallbackFcn(app, @OutputfolderTextAreaValueChanged, true);
      app.OutputfolderTextArea.ValueChangingFcn = createCallbackFcn(app, @OutputfolderTextAreaValueChanging, true);
      app.RemoveripszipsButton = uibutton(app.HomeTab, 'push');
      app.RemoveripszipsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveripszipsButtonPushed, true);
      app.RemoveripszipsButton.Text = 'Remove rips/zips';
      app.ExportfigureButton = uibutton(app.HomeTab, 'push');
      app.ExportfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfigureButtonPushed, true);
      app.ExportfigureButton.Text = 'Export figure';
      app.FilelistindexSpinner = uispinner(app.HomeTab);
      app.FilelistindexSpinner.ValueChangedFcn = createCallbackFcn(app, @FilelistindexSpinnerValueChanged, true);
      app.FilelistindexSpinner.Visible = 'off';
      app.UseFilelistmCheckBox = uicheckbox(app.HomeTab);
      app.UseFilelistmCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFilelistmCheckBoxValueChanged, true);
      app.UseFilelistmCheckBox.Tooltip = {'Function Filelist in Datafolder should return a list of file names'};
      app.UseFilelistmCheckBox.Text = 'Use Filelist.m';
      app.FilelistindexLabel = uilabel(app.HomeTab);
      app.FilelistindexLabel.Visible = 'off';
      app.FilelistindexLabel.Text = 'Filelist index';
      app.ChangeStatusButton = uibutton(app.HomeTab, 'push');
      app.ChangeStatusButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeStatusButtonPushed, true);
      app.ChangeStatusButton.Tooltip = {'Read  data from output tables Trop and Tzip'};
      app.ChangeStatusButton.Text = 'Revert to original';
      app.Label = uilabel(app.HomeTab);
      app.StatusLabel = uilabel(app.HomeTab);
      app.StatusLabel.Text = 'Status label';
      app.StatusLabel_2 = uilabel(app.HomeTab);
      app.StatusLabel_2.Text = 'Status:';
      app.SinglefvsxtracesTab = uitab(app.TabGroup);
      app.SinglefvsxtracesTab.Title = 'Single f vs x traces';
      app.UIAxes3 = uiaxes(app.SinglefvsxtracesTab);
      title(app.UIAxes3, 'Title')
      xlabel(app.UIAxes3, 'X')
      ylabel(app.UIAxes3, 'Y')
      zlabel(app.UIAxes3, 'Z')
      app.UIAxes4 = uiaxes(app.SinglefvsxtracesTab);
      title(app.UIAxes4, 'Title')
      xlabel(app.UIAxes4, 'X')
      ylabel(app.UIAxes4, 'Y')
      zlabel(app.UIAxes4, 'Z')
      app.CyclenoSpinnerLabel = uilabel(app.SinglefvsxtracesTab);
      app.CyclenoSpinnerLabel.Text = 'Cycle no';
      app.CyclenoSpinner = uispinner(app.SinglefvsxtracesTab);
      app.CyclenoSpinner.ValueChangedFcn = createCallbackFcn(app, @CyclenoSpinnerValueChanged, true);
      app.CycletypeButtonGroup = uibuttongroup(app.SinglefvsxtracesTab);
      app.CycletypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CycletypeButtonGroupSelectionChanged, true);
      app.CycletypeButtonGroup.Title = 'Cycle type';
      app.PullrelaxButton = uiradiobutton(app.CycletypeButtonGroup);
      app.PullrelaxButton.Text = 'Pull-relax';
      app.PullrelaxButton.Value = true;
      app.RelaxpullButton = uiradiobutton(app.CycletypeButtonGroup);
      app.RelaxpullButton.Text = 'Relax-pull';
      app.Trace1Label = uilabel(app.SinglefvsxtracesTab);
      app.Trace1Label.Text = 'Trace1';
      app.StartLabel = uilabel(app.SinglefvsxtracesTab);
      app.StartLabel.Text = 'Start';
      app.t11Label = uilabel(app.SinglefvsxtracesTab);
      app.t11Label.Text = 't11';
      app.Event1Label = uilabel(app.SinglefvsxtracesTab);
      app.Event1Label.Text = 'Event1';
      app.t12Label = uilabel(app.SinglefvsxtracesTab);
      app.t12Label.Text = 't12';
      app.EndLabel = uilabel(app.SinglefvsxtracesTab);
      app.EndLabel.Text = 'End';
      app.t13Label = uilabel(app.SinglefvsxtracesTab);
      app.t13Label.Text = 't13';
      app.ForceLabel = uilabel(app.SinglefvsxtracesTab);
      app.ForceLabel.Text = 'Force';
      app.f1Label = uilabel(app.SinglefvsxtracesTab);
      app.f1Label.Text = 'f1';
      app.xLabel = uilabel(app.SinglefvsxtracesTab);
      app.xLabel.Text = 'Δx';
      app.dx1Label = uilabel(app.SinglefvsxtracesTab);
      app.dx1Label.Text = 'dx1';
      app.Trace2Label = uilabel(app.SinglefvsxtracesTab);
      app.Trace2Label.Text = 'Trace2';
      app.StartLabel_2 = uilabel(app.SinglefvsxtracesTab);
      app.StartLabel_2.Text = 'Start';
      app.t21Label = uilabel(app.SinglefvsxtracesTab);
      app.t21Label.Text = 't21';
      app.Event2Label = uilabel(app.SinglefvsxtracesTab);
      app.Event2Label.Text = 'Event2';
      app.t22Label = uilabel(app.SinglefvsxtracesTab);
      app.t22Label.Text = 't22';
      app.EndLabel_2 = uilabel(app.SinglefvsxtracesTab);
      app.EndLabel_2.Text = 'End';
      app.t23Label = uilabel(app.SinglefvsxtracesTab);
      app.t23Label.Text = 't23';
      app.ForceLabel_2 = uilabel(app.SinglefvsxtracesTab);
      app.ForceLabel_2.Text = 'Force';
      app.f2Label = uilabel(app.SinglefvsxtracesTab);
      app.f2Label.Text = 'f2';
      app.xLabel_2 = uilabel(app.SinglefvsxtracesTab);
      app.xLabel_2.Text = 'Δx';
      app.dx2Label = uilabel(app.SinglefvsxtracesTab);
      app.dx2Label.Text = 'dx2';
      app.ExportfigureButton_2 = uibutton(app.SinglefvsxtracesTab, 'push');
      app.ExportfigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportfigureButton_2Pushed, true);
      app.ExportfigureButton_2.Text = 'Export figure';
      app.allcyclesLabel = uilabel(app.SinglefvsxtracesTab);
      app.allcyclesLabel.Text = 'allcycles';
      app.ShowtracesButtonGroup = uibuttongroup(app.SinglefvsxtracesTab);
      app.ShowtracesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowtracesButtonGroupSelectionChanged, true);
      app.ShowtracesButtonGroup.Title = 'Show traces';
      app.BothButton = uiradiobutton(app.ShowtracesButtonGroup);
      app.BothButton.Text = 'Both';
      app.BothButton.Value = true;
      app.PullButton = uiradiobutton(app.ShowtracesButtonGroup);
      app.PullButton.Text = 'Pull';
      app.RelaxButton = uiradiobutton(app.ShowtracesButtonGroup);
      app.RelaxButton.Text = 'Relax';
      app.HidefittedlinesCheckBox = uicheckbox(app.SinglefvsxtracesTab);
      app.HidefittedlinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @HidefittedlinesCheckBoxValueChanged, true);
      app.HidefittedlinesCheckBox.Text = 'Hide fitted lines';
      app.MultiplefvsxtracesTab = uitab(app.TabGroup);
      app.MultiplefvsxtracesTab.Title = 'Multiple f vs x traces';
      app.UIAxes6 = uiaxes(app.MultiplefvsxtracesTab);
      title(app.UIAxes6, 'Title')
      xlabel(app.UIAxes6, 'X')
      ylabel(app.UIAxes6, 'Y')
      zlabel(app.UIAxes6, 'Z')
      app.UIAxes5 = uiaxes(app.MultiplefvsxtracesTab);
      title(app.UIAxes5, 'Title')
      xlabel(app.UIAxes5, 'X')
      ylabel(app.UIAxes5, 'Y')
      zlabel(app.UIAxes5, 'Z')
      app.CyclespacingnmEditFieldLabel = uilabel(app.MultiplefvsxtracesTab);
      app.CyclespacingnmEditFieldLabel.Text = 'Cycle spacing (nm)';
      app.CyclespacingnmEditField = uieditfield(app.MultiplefvsxtracesTab, 'numeric');
      app.CyclespacingnmEditField.ValueChangedFcn = createCallbackFcn(app, @CyclespacingnmEditFieldValueChanged, true);
      app.CyclespacingnmEditField.Value = 100;
      app.NumberofcyclesEditFieldLabel = uilabel(app.MultiplefvsxtracesTab);
      app.NumberofcyclesEditFieldLabel.Text = 'Number of cycles';
      app.NumberofcyclesEditField = uieditfield(app.MultiplefvsxtracesTab, 'numeric');
      app.NumberofcyclesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofcyclesEditFieldValueChanged, true);
      app.NumberofcyclesEditField.Value = 4;
      app.StarttimeEditFieldLabel = uilabel(app.MultiplefvsxtracesTab);
      app.StarttimeEditFieldLabel.Text = 'Start time';
      app.StarttimeEditField = uieditfield(app.MultiplefvsxtracesTab, 'numeric');
      app.StarttimeEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimeEditFieldValueChanged, true);
      app.ExportgraphButton = uibutton(app.MultiplefvsxtracesTab, 'push');
      app.ExportgraphButton.ButtonPushedFcn = createCallbackFcn(app, @ExportgraphButtonPushed, true);
      app.ExportgraphButton.Text = 'Export graph';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = RipAnalysis(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end